classdef Nuclear_Segmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NuclearSegmentationFromDAPIUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        OutputFormatDropDown           matlab.ui.control.DropDown
        OutputFormatDropDownLabel      matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        SplitadjacentsCheckBox         matlab.ui.control.CheckBox
        Step5SelectwhethertoapplysplittingLabel  matlab.ui.control.Label
        NucleiThresholdEditField       matlab.ui.control.NumericEditField
        NucleiThresholdEditFieldLabel  matlab.ui.control.Label
        MaximumSizeSlider              matlab.ui.control.Slider
        MaximumSizeSliderLabel         matlab.ui.control.Label
        MinimumSizeSlider              matlab.ui.control.Slider
        MinimumSizeSliderLabel         matlab.ui.control.Label
        Step4SelectminimummaximumsizerangeLabel  matlab.ui.control.Label
        Step3SelectthresholdLabel      matlab.ui.control.Label
        Step2SelectyourpatchsizeLabel  matlab.ui.control.Label
        Step1SelectyourslideandoutputformatLabel  matlab.ui.control.Label
        PatchsizeEditField             matlab.ui.control.NumericEditField
        PatchsizeEditFieldLabel        matlab.ui.control.Label
        SlideNameLabel                 matlab.ui.control.Label
        SelectSlideButton              matlab.ui.control.Button
        SegmentationParametersLabel    matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        ApplytoremainingpatchesButton  matlab.ui.control.Button
        PreviousPatchButton            matlab.ui.control.Button
        NextPatchButton                matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        SidebySideTab                  matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        OverlaidTab                    matlab.ui.container.Tab
        NuclearMaskOpacitySlider       matlab.ui.control.Slider
        NuclearMaskOpacitySliderLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        ImageViewLabel                 matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        
        SlidePath
        OutputType
        PatchSize = 1024
        BigImageHolder
        FluorImage
        NucMask
        OverlayImage
        ThreshVal = 128
        MinSize = 0
        MaxSize = 100000
        Split = true
        SplitVal = 0.1
        PatchNumber
        PatchBBox

        Annotations
        SlideExt

        ImageFormats = {'svs','tif','tiff','jpg','jpeg','png'};

    end
    
    methods (Access = public)
        
        function EnableStuff(app)
            app.PatchsizeEditField.Enable = 'on';
            app.NucleiThresholdEditField.Enable = 'on';
            app.MinimumSizeSlider.Enable = 'on';
            app.MaximumSizeSlider.Enable = 'on';
            app.SplitadjacentsCheckBox.Enable = 'on';
            app.Slider.Enable = 'on';

            app.NextPatchButton.Enable = 'on';
            
        end
        
        function SegmentPatch(app,new)

            % Getting the pixel start and end coordinates from the current
            % patch numbers in both directions
            if new
                [patch_start_pixels,patch_end_pixels] = GetPatchCoordinates(app);
            else
                patch_end_pixels = {'yes'};
            end

            if ~isempty(patch_end_pixels)
                if new
                    app.PatchBBox = [patch_start_pixels(1),patch_end_pixels(1),patch_start_pixels(2),patch_end_pixels(2)];
                    patch_start_pixels
                    patch_end_pixels
                    app.FluorImage = getRegion(app.BigImageHolder,1,patch_start_pixels,patch_end_pixels);
                end

                % Applying current threshold
                thresh_image = imbinarize(app.FluorImage,(1/255)*app.ThreshVal);
                
                if sum(sum(thresh_image))>0
                    % Setting min and max size
                    nuc_areas = struct2array(regionprops('struct',thresh_image,'Area'));
                    
                    if isempty(find(app.MaximumSizeSlider.Limits==max(nuc_areas)))
                        app.MinimumSizeSlider.Limits = [0,max(nuc_areas-1)];
                        app.MaximumSizeSlider.Limits = [0,max(nuc_areas)];
                        app.MinimumSizeSlider.Value = 0;
                        app.MaximumSizeSlider.Value = max(nuc_areas);
                    end
    
                    size_filtered = bwareafilt(thresh_image,[app.MinSize,app.MaxSize]);
                    
                    % If splitting, and by how much
                    if app.Split
                        nuclei_mask = SplitNuclei(app,size_filtered);
                    else
                        nuclei_mask = size_filtered;
                    end
                else
                    nuclei_mask = zeros(size(app.FluorImage,1),size(app.FluorImage,2));
                end
                app.NucMask = nuclei_mask;

                app.OverlayImage = labeloverlay(app.FluorImage,app.NucMask,...
                    'Transparency',round(0.01*app.NuclearMaskOpacitySlider.Value));

            else
                msgbox('All Done!')
            end
            test = 'done'

        end
        
        function [patch_start_pixels,patch_end_pixels] = GetPatchCoordinates(app)
            
            % Going horizontal and then vertical with these (will start at
            % 1 when currentvertical or currenthorizontal are 1)
            patch_start_pixels = [1+(app.PatchSize*(app.PatchNumber.CurrentHorizontal-1)),1+(app.PatchSize*(app.PatchNumber.CurrentVertical-1))];

            if app.PatchNumber.Current+1<=app.PatchNumber.Total
                app.PatchNumber.Current = app.PatchNumber.Current+1;
                
                % Updating patch number in both directions
                if app.PatchNumber.CurrentHorizontal+1<=app.PatchNumber.TotalHorizontal

                    app.PatchNumber.CurrentHorizontal = app.PatchNumber.CurrentHorizontal+1;

                    patch_end_pixels = [1+(app.PatchSize*(app.PatchNumber.CurrentHorizontal-1)),1+(app.PatchSize*(app.PatchNumber.CurrentVertical))];
                else
                    if app.PatchNumber.CurrentVertical+1<=app.PatchNumber.TotalVertical
                        app.PatchNumber.CurrentHorizontal = 1;
                        app.PatchNumber.CurrentVertical = app.PatchNumber.CurrentVertical+1;

                        patch_end_pixels = [1+(app.PatchSize*(app.PatchNumber.CurrentHorizontal-1)),1+(app.PatchSize*(app.PatchNumber.CurrentVertical-1))];

                    else
                        patch_end_pixels = [];
                    end

                end

            else
                patch_end_pixels = [];
            end
            
        end
        
        function splitted_nuclei = SplitNuclei(app,nuc_mask)
            
            nuc_mask(1,:) = 0;
            nuc_mask(end,:) = 0;
            nuc_mask(:,1) = 0;
            nuc_mask(:,end) = 0;

            ndt = bwdist(~nuc_mask);
            ndtf = imgaussfilt(ndt,1);

            nuc_peaks = imextendedmax(ndtf,app.SplitVal*max(ndtf(:)));
            multiple_nucs = bwpropfilt((nuc_mask-nuc_peaks)>0,'EulerNumber',[-100,-1]);
            multiple_nucs = imfill(multiple_nucs,'holes');

            single_nucs = nuc_mask-imfill(multiple_nucs,'holes');
            
            % Identify skeleton by influence zones for marker-based
            % watershed
            mn_dt=bwdist(multiple_nucs);
            mn_peaks=nuc_peaks&multiple_nucs;
            mn_w=watershed(mn_dt);
            ridgelines=mn_w==0;
            
            % Perform marker guided watershed using estimated peaks and ridgelines as
            % markers
            guided_watershed=imimposemin(mn_dt,ridgelines|mn_peaks);
            L=watershed(guided_watershed);
            segmented_nuclei=multiple_nucs-(L==0)>0;
            segmented_nuclei=bwareaopen(segmented_nuclei,app.MinSize);
            
            %Lightly round off the sharp corners produced by watershed splitting
            segmented_nuclei=imopen(segmented_nuclei,strel('disk',1));
            
            %Combine split nuclei and single nuclei
            splitted_nuclei=segmented_nuclei|single_nucs;

            
        end
        
        function UpdateImages(app)
            % Show current images
            imshow(app.FluorImage,'Parent',app.UIAxes2), title('Fluorescence Image','Parent',app.UIAxes2)
            imshow(255.*uint8(app.NucMask),'Parent',app.UIAxes3), title('Nuclei Mask','Parent',app.UIAxes3)
            imshow(app.OverlayImage,'Parent',app.UIAxes), title('Overlaid Nuclei Mask','Parent',app.UIAxes)
                
        end
        
        function WriteAnnotations(app)

            labeled_image = bwlabel(app.NucMask);
            n_nuclei = max(labeled_image,[],'all');
            
            if isempty(app.Annotations)
                app.Annotations = {};
                count = 1;
            else
                count = length(app.Annotations)+1;
            end
            
            if n_nuclei>0
                for i = 1:n_nuclei
    
                    % Individual nuclei mask
                    individual_image = labeled_image==i;
                    % Getting the coordinates of the boundary
                    [y_coords,x_coords] = find(bwperim(individual_image));
                    
                    % Modifying these coords so they are in the WSI coordinate system as
                    % opposed to the extracted image region. This is done by just adding
                    % the minimum x and y coordinate used to extract the region.
                    y_coords = y_coords+app.PatchBBox(3);
                    x_coords = x_coords+app.PatchBBox(1);
                
                    % Adding vertex coordinates to a struct.
                    new_region = [];
                    for j = 1:length(x_coords)
                        new_region.Vertices.Vertex{1,j}.Attributes.X = num2str(round(x_coords(j)));
                        new_region.Vertices.Vertex{1,j}.Attributes.Y = num2str(round(y_coords(j)));
                
                    end
                    % Adding a region to the XML file.
                    app.Annotations.Region{count} = new_region;
                    count = count+1;
                
                end
            
                % Adding all of this to the main struct
                annotations_xml.Annotations.Annotation.Regions = app.Annotations;
                struct2xml(annotations_xml,strrep(app.SlidePath,app.SlideExt,'xml'))
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectSlideButton
        function SelectSlideButtonPushed(app, event)
            
            % Selecting WSI file
            [filename,path] = uigetfile('*','Select Image file');
            if ~path==0
                
                % Checking if file is the right extension
                if contains(filename,app.ImageFormats)
                    app.SlidePath = strcat(path,filesep,filename);
                    app.SlideNameLabel.Text = strjoin({'Slide Name: '},{filename});

                    app.SlideExt = strsplit(filename,'.');
                    app.SlideExt = app.SlideExt{end};
                    
                    EnableStuff(app)
                    
                    app.BigImageHolder = bigimage(app.SlidePath);

                    % Determine if patch size is larger than whole image
                    level_sizes = app.BigImageHolder.LevelSizes;
                    % Assuming level 1 is the highest resolution
                    if any(level_sizes(1,:)<app.PatchSize)
                        app.PatchSize = min(max(level_sizes(1,:)));
                        app.PatchsizeEditField.Value = app.PatchSize;
                        app.PatchsizeEditField.Limits = [0,app.PatchSize];
                    end

                    % Get total number of patches available in the image
                    hi_res_area = level_sizes(1,1)*level_sizes(1,2);
                    app.PatchNumber.Total = floor(hi_res_area/(app.PatchSize^2));
                    app.PatchNumber.TotalHorizontal = floor(level_sizes(1,2)/app.PatchSize);
                    app.PatchNumber.TotalVertical = floor(level_sizes(1,1)/app.PatchSize);
                    app.PatchNumber.Current = 1;
                    app.PatchNumber.CurrentHorizontal = 1;
                    app.PatchNumber.CurrentVertical = 1;

                    SegmentPatch(app,true)
                    UpdateImages(app)
                    WriteAnnotations(app)
                
                else
                    msgbox(strcat('Oops! Currently the file:_',filename,'_is not a readable format! :/'))
                end

            end


        end

        % Value changed function: PatchsizeEditField
        function PatchsizeEditFieldValueChanged(app, event)
            value = app.PatchsizeEditField.Value;
            app.PatchSize = value;

            SegmentPatch(app,false)
            UpdateImages(app)

        end

        % Value changed function: NucleiThresholdEditField
        function NucleiThresholdEditFieldValueChanged(app, event)
            value = app.NucleiThresholdEditField.Value;
            
            app.ThreshVal = value;

            SegmentPatch(app,false)
            UpdateImages(app)

        end

        % Value changed function: MinimumSizeSlider
        function MinimumSizeSliderValueChanged(app, event)
            value = app.MinimumSizeSlider.Value;
            
            app.MinSize = round(value);
            SegmentPatch(app,false)
            UpdateImages(app)

        end

        % Value changed function: MaximumSizeSlider
        function MaximumSizeSliderValueChanged(app, event)
            value = app.MaximumSizeSlider.Value;
            
            app.MaxSize = round(value);
            SegmentPatch(app,false)
            UpdateImages(app)

        end

        % Value changed function: SplitadjacentsCheckBox
        function SplitadjacentsCheckBoxValueChanged(app, event)
            value = app.SplitadjacentsCheckBox.Value;
            app.Split = value;

            SegmentPatch(app,false)
            UpdateImages(app)

            if value
                app.Slider.Enable = 'on';
            else
                app.Slider.Enable = 'off';
            end

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            
            app.SplitVal = value/100;
            SegmentPatch(app,false)
            UpdateImages(app)
            
        end

        % Button pushed function: NextPatchButton
        function NextPatchButtonPushed(app, event)
            
            % Going to the next patch
            SegmentPatch(app,true)
            UpdateImages(app)

            % TODO: Write annotations to file
            WriteAnnotations(app)

        end

        % Button pushed function: PreviousPatchButton
        function PreviousPatchButtonPushed(app, event)
            
            % Go back to previous patch
            if app.PatchNumber.Current ~=1
                if app.PatchNumber.CurrentHorizontal~=1
                    app.PatchNumber.CurrentHorizontal = app.PatchNumber.CurrentHorizontal-1;
                else
                    app.PatchNumber.CurrentHorizontal = app.PatchNumber.TotalHorizontal-1;
                    app.PatchNumber.CurrentVertical = app.PatchNumber.CurrentVertical-1;
                end
                app.PatchNumber.Current = app.PatchNumber.Current-1;
    
                SegmentPatch(app,true)
                UpdateImages(app)
            else
                msgbox('You are on the first patch still!')
            end

        end

        % Button pushed function: ApplytoremainingpatchesButton
        function ApplytoremainingpatchesButtonPushed(app, event)
            
            % Find number of remaining patches
            remaining_patches = app.PatchNumber.Total-app.PatchNumber.Current;
            
            patch_wait = waitbar(0,'Segmenting remaining nuclei','CreateCancelBtn',...
                'setappdata(gcbf,''canceling'',1)');
            setappdata(patch_wait,'canceling',0);


            start_patch_numbers = app.PatchNumber;


            for i = 1:remaining_patches
                
                if getappdata(patch_wait,'canceling')
                    % Cancel and go back to previous
                    app.PatchNumber = start_patch_numbers;
                    SegmentPatch(app,true)
                    UpdateImages(app)

                    break
                end

                SegmentPatch(app,true)

                % TODO: Write segmentation to annotation file

                waitbar(i/remaining_patches,patch_wait,strcat('Segmenting remaining nuclei:',num2str(i),'/',num2str(remaining_patches)))


            end

            waitbar(1,patch_wait,'All Done!')
            pause(1)
            close(patch_wait)


        end

        % Value changed function: NuclearMaskOpacitySlider
        function NuclearMaskOpacitySliderValueChanged(app, event)
            value = app.NuclearMaskOpacitySlider.Value;
            
            app.OverlayImage = labeloverlay(app.FluorImage,app.NucMask,...
                'Transparency',round(0.01*value));

            UpdateImages(app)


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.NuclearSegmentationFromDAPIUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {516, 516};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {262, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NuclearSegmentationFromDAPIUIFigure = uifigure('Visible', 'off');
            app.NuclearSegmentationFromDAPIUIFigure.Name = 'Nuclear Segmentation From DAPI';
            app.NuclearSegmentationFromDAPIUIFigure.Icon = 'hubmap-person-and-text-logo.png';
            app.NuclearSegmentationFromDAPIUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.NuclearSegmentationFromDAPIUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SegmentationParametersLabel = uilabel(app.LeftPanel);
            app.SegmentationParametersLabel.Text = 'Segmentation Parameters';
            app.SelectSlideButton = uibutton(app.LeftPanel, 'push');
            app.SelectSlideButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSlideButtonPushed, true);
            app.SelectSlideButton.Text = 'Select Slide';
            app.SlideNameLabel = uilabel(app.LeftPanel);
            app.SlideNameLabel.Text = 'Slide Name: ';
            app.PatchsizeEditFieldLabel = uilabel(app.LeftPanel);
            app.PatchsizeEditFieldLabel.Text = 'Patch size';
            app.PatchsizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PatchsizeEditField.Limits = [0 2000];
            app.PatchsizeEditField.ValueChangedFcn = createCallbackFcn(app, @PatchsizeEditFieldValueChanged, true);
            app.PatchsizeEditField.Enable = 'off';
            app.PatchsizeEditField.Value = 1024;
            app.Step1SelectyourslideandoutputformatLabel = uilabel(app.LeftPanel);
            app.Step1SelectyourslideandoutputformatLabel.Text = 'Step 1: Select your slide and output format';
            app.Step2SelectyourpatchsizeLabel = uilabel(app.LeftPanel);
            app.Step2SelectyourpatchsizeLabel.Text = 'Step 2: Select your patch size';
            app.Step3SelectthresholdLabel = uilabel(app.LeftPanel);
            app.Step3SelectthresholdLabel.Text = 'Step 3: Select threshold';
            app.Step4SelectminimummaximumsizerangeLabel = uilabel(app.LeftPanel);
            app.Step4SelectminimummaximumsizerangeLabel.Text = 'Step 4: Select minimum/maximum size range';
            app.MinimumSizeSliderLabel = uilabel(app.LeftPanel);
            app.MinimumSizeSliderLabel.Text = 'Minimum Size';
            app.MinimumSizeSlider = uislider(app.LeftPanel);
            app.MinimumSizeSlider.ValueChangedFcn = createCallbackFcn(app, @MinimumSizeSliderValueChanged, true);
            app.MinimumSizeSlider.Enable = 'off';
            app.MaximumSizeSliderLabel = uilabel(app.LeftPanel);
            app.MaximumSizeSliderLabel.Text = 'Maximum Size';
            app.MaximumSizeSlider = uislider(app.LeftPanel);
            app.MaximumSizeSlider.ValueChangedFcn = createCallbackFcn(app, @MaximumSizeSliderValueChanged, true);
            app.MaximumSizeSlider.Enable = 'off';
            app.MaximumSizeSlider.Value = 100;
            app.NucleiThresholdEditFieldLabel = uilabel(app.LeftPanel);
            app.NucleiThresholdEditFieldLabel.Text = 'Nuclei Threshold';
            app.NucleiThresholdEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NucleiThresholdEditField.Limits = [0 255];
            app.NucleiThresholdEditField.RoundFractionalValues = 'on';
            app.NucleiThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @NucleiThresholdEditFieldValueChanged, true);
            app.NucleiThresholdEditField.Enable = 'off';
            app.NucleiThresholdEditField.Value = 128;
            app.Step5SelectwhethertoapplysplittingLabel = uilabel(app.LeftPanel);
            app.Step5SelectwhethertoapplysplittingLabel.Text = 'Step 5: Select whether to apply splitting';
            app.SplitadjacentsCheckBox = uicheckbox(app.LeftPanel);
            app.SplitadjacentsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SplitadjacentsCheckBoxValueChanged, true);
            app.SplitadjacentsCheckBox.Enable = 'off';
            app.SplitadjacentsCheckBox.Text = 'Split adjacents';
            app.SplitadjacentsCheckBox.Value = true;
            app.Slider = uislider(app.LeftPanel);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Enable = 'off';
            app.Slider.Value = 1;
            app.OutputFormatDropDownLabel = uilabel(app.LeftPanel);
            app.OutputFormatDropDownLabel.Text = 'Output Format';
            app.OutputFormatDropDown = uidropdown(app.LeftPanel);
            app.OutputFormatDropDown.Items = {'Aperio XML', ''};
            app.OutputFormatDropDown.Value = 'Aperio XML';
            app.RightPanel = uipanel(app.GridLayout);
            app.ImageViewLabel = uilabel(app.RightPanel);
            app.ImageViewLabel.Text = 'Image View';
            app.TabGroup = uitabgroup(app.RightPanel);
            app.SidebySideTab = uitab(app.TabGroup);
            app.SidebySideTab.Title = 'Side-by-Side';
            app.UIAxes2 = uiaxes(app.SidebySideTab);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.SidebySideTab);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.OverlaidTab = uitab(app.TabGroup);
            app.OverlaidTab.Title = 'Overlaid';
            app.UIAxes = uiaxes(app.OverlaidTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.NuclearMaskOpacitySliderLabel = uilabel(app.OverlaidTab);
            app.NuclearMaskOpacitySliderLabel.Text = 'Nuclear Mask Opacity';
            app.NuclearMaskOpacitySlider = uislider(app.OverlaidTab);
            app.NuclearMaskOpacitySlider.ValueChangedFcn = createCallbackFcn(app, @NuclearMaskOpacitySliderValueChanged, true);
            app.NextPatchButton = uibutton(app.RightPanel, 'push');
            app.NextPatchButton.ButtonPushedFcn = createCallbackFcn(app, @NextPatchButtonPushed, true);
            app.NextPatchButton.Enable = 'off';
            app.NextPatchButton.Text = 'Next Patch';
            app.PreviousPatchButton = uibutton(app.RightPanel, 'push');
            app.PreviousPatchButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousPatchButtonPushed, true);
            app.PreviousPatchButton.Enable = 'off';
            app.PreviousPatchButton.Text = 'Previous Patch';
            app.ApplytoremainingpatchesButton = uibutton(app.RightPanel, 'push');
            app.ApplytoremainingpatchesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoremainingpatchesButtonPushed, true);
            app.ApplytoremainingpatchesButton.Enable = 'off';
            app.ApplytoremainingpatchesButton.Text = 'Apply to remaining patches';
            % Show the figure after all components are created
            app.NuclearSegmentationFromDAPIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Nuclear_Segmentation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NuclearSegmentationFromDAPIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NuclearSegmentationFromDAPIUIFigure)
        end
    end
end